classdef design_file < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveProcessedImageButton  matlab.ui.control.Button
        CompareButton             matlab.ui.control.StateButton
        ApplyButton               matlab.ui.control.Button
        OperationsDropDown        matlab.ui.control.DropDown
        OperationsDropDownLabel   matlab.ui.control.Label
        AppliedOperationLabel     matlab.ui.control.Label
        LoadImageButton           matlab.ui.control.Button
        LargeAxis                 matlab.ui.control.UIAxes
        SmallAxis                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage % Stores the loaded image
        ProcessedImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function loadImage(app, event)

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Creating variable for the process name and grayscaled image
            processToApply = app.OperationsDropDown.Value;
            grayscaled = rgb2gray(app.OriginalImage);

            % Making the space ready
            app.SaveProcessedImageButton.Visible = "on";
            app.CompareButton.Visible = "on";
            app.AppliedOperationLabel.Visible = "on";
            app.LargeAxis.Visible = "on";
            cla(app.LargeAxis);  % Clearing any old plots before showing new image
            hold(app.LargeAxis, "on");
            axis(app.LargeAxis, "image");
            
            % Switching operation for each operator
            switch processToApply
                case "Grayscale"
                    app.ProcessedImage = grayscaled;
                    imshow(grayscaled, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Grayscale Effect";
                case "Sobel"
                    app.ProcessedImage = edge(grayscaled, "sobel");
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Sobel Edge Detection";
                case "Prewitt"
                    app.ProcessedImage = edge(grayscaled, "prewitt");
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Prewitt Edge Detection";
                case "Canny"
                    app.ProcessedImage = edge(grayscaled, "canny");
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Canny Edge Detection";
                case "Gaussian Filter"
                    app.ProcessedImage = imgaussfilt(app.OriginalImage, 2);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Gaussian Filter";
                case "Median Filter"
                    app.ProcessedImage = medfilt2(grayscaled, [3 3]);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Median Filter";
                case "Weiner Filter"
                    app.ProcessedImage = wiener2(grayscaled, [5 5]);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Weiner Filter";
                case "Bilateral Filter"
                    app.ProcessedImage = imbilatfilt(app.OriginalImage);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Bilateral Filter";
                case "Sharpen Filter"
                    app.ProcessedImage = imsharpen(app.OriginalImage);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Sharpen Filter";
                case "Adaptive Histogram Equalization"
                    app.ProcessedImage = adapthisteq(grayscaled);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Operated Adaptive Histogram Equalization";
                case "Adjust Image Brightness/Contrast"
                    R_adj = imadjust(app.OriginalImage(:,:,1));
                    G_adj = imadjust(app.OriginalImage(:,:,2));
                    B_adj = imadjust(app.OriginalImage(:,:,3));

                    app.ProcessedImage = cat(3, R_adj, G_adj, B_adj);
                    imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                    app.AppliedOperationLabel.Text = "Adjusted Image Brightness/Contrast";
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg; *.png', 'Image Files (*.jpg, *.png)'}, 'Select an Image File');   % Asking the user for the image
            
            % Checking if the user selected an image file
            if isequal(fileName, 0) 
                return; % User canceled
            end
            
            % Setting variable for the image file
            fullPath = fullfile(pathName, fileName);
            app.OriginalImage = imread(fullPath);

            % Clear any old plots before showing new image
            cla(app.SmallAxis);  
            cla(app.LargeAxis);
            hold(app.SmallAxis, "on");
            axis(app.SmallAxis, "image");

            % Showing the original image in both axes
            imshow(app.OriginalImage, 'Parent', app.SmallAxis);


            % Enabling Operation Dropdown, Apply Button and Large Axis Label
            app.OperationsDropDown.Visible = "on";
            app.OperationsDropDownLabel.Visible = "on";
            app.ApplyButton.Visible = "on";
            app.ApplyButton.Enable = "on";
        end

        % Value changed function: CompareButton
        function CompareButtonValueChanged(app, event)
            value = app.CompareButton.Value;
            if value
                imshow(app.OriginalImage, 'Parent', app.LargeAxis);
                app.CompareButton.Text = "Turn off";
            else
                imshow(app.ProcessedImage, 'Parent', app.LargeAxis);
                app.CompareButton.Text = "Compare";
            end
        end

        % Button pushed function: SaveProcessedImageButton
        function SaveProcessedImageButtonPushed(app, event)
            % Opening the Diaglog Box
            [file, path] = uiputfile({'&.png'; '*.jpg'}, 'Save Processed Image');

            % Checking if the user canceled or not
            if isequal(file, 0)
                return;
            end

            % Getting full path and saving the image
            imwrite(app.ProcessedImage, fullfile(path, file));
            uialert(app.UIFigure, 'Image saved successfully', 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SmallAxis = uiaxes(app.UIFigure);
            title(app.SmallAxis, 'Original Image Viewer')
            app.SmallAxis.XLim = [0 1];
            app.SmallAxis.XTick = [];
            app.SmallAxis.YTick = [];
            app.SmallAxis.Tag = 'SmallAxis';
            app.LargeAxis = uiaxes(app.UIFigure);
            title(app.LargeAxis, 'Processed Image')
            app.LargeAxis.XTick = [];
            app.LargeAxis.YTick = [];
            app.LargeAxis.Tag = 'LargeAxis';
            app.LargeAxis.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AppliedOperationLabel = uilabel(app.UIFigure);
            app.AppliedOperationLabel.Visible = 'off';
            app.AppliedOperationLabel.Text = 'Applied Operation';
            app.OperationsDropDownLabel = uilabel(app.UIFigure);
            app.OperationsDropDownLabel.Visible = 'off';
            app.OperationsDropDownLabel.Text = 'Operations';
            app.OperationsDropDown = uidropdown(app.UIFigure);
            app.OperationsDropDown.Items = {'Grayscale', 'Sobel', 'Prewitt', 'Canny', 'Gaussian Filter', 'Median Filter', 'Weiner Filter', 'Bilateral Filter', 'Sharpen Filter', 'Adaptive Histogram Equalization', 'Adjust Image Brightness/Contrast'};
            app.OperationsDropDown.Visible = 'off';
            app.OperationsDropDown.Value = 'Grayscale';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Visible = 'off';
            app.ApplyButton.Text = 'Apply';
            app.CompareButton = uibutton(app.UIFigure, 'state');
            app.CompareButton.ValueChangedFcn = createCallbackFcn(app, @CompareButtonValueChanged, true);
            app.CompareButton.Visible = 'off';
            app.CompareButton.Text = 'Compare';
            app.SaveProcessedImageButton = uibutton(app.UIFigure, 'push');
            app.SaveProcessedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedImageButtonPushed, true);
            app.SaveProcessedImageButton.Visible = 'off';
            app.SaveProcessedImageButton.Text = 'Save Processed Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_file

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end